classdef Konwerter_mnoznik_final2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Programzaliczeniowy2022rWiktorWrona259900Label  matlab.ui.control.Label
        InstrukcjaprzeliczaniaseryjnegoButton  matlab.ui.control.Button
        ObliczButton                    matlab.ui.control.Button
        ImportujdanedoobliczeButton     matlab.ui.control.Button
        ZapiszplikwynikowyButton        matlab.ui.control.Button
        PrzedrostekLabel                matlab.ui.control.Label
        JednostkaLabel                  matlab.ui.control.Label
        MnonikDropDown_3                matlab.ui.control.DropDown
        JednostkaDropDown_2             matlab.ui.control.DropDown
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        MnonikDropDown_2                matlab.ui.control.DropDown
        WartowyjciowaEditField          matlab.ui.control.NumericEditField
        WartowyjciowaLabel              matlab.ui.control.Label
        JednostkaDropDown               matlab.ui.control.DropDown
        WartowejciowaEditField          matlab.ui.control.NumericEditField
        WartowejciowaEditFieldLabel     matlab.ui.control.Label
        KonwerterjednostekenergiiLabel  matlab.ui.control.Label
        MenuprzeliczaniaseryjnegoLabel  matlab.ui.control.Label
    end

%     ustalenie parametrów początkowych
    properties (Access = public)
        j1 = "Dżul [J]"
        j2 = "Dżul [J]"
        wartosc = 0
        plikwynik = ""
        sciezkawynik = ""
        dlugoscdanych = 0
        dane = []
        status = "Przeliczono z Dżul [J], na Dżul [J]"
    end
% funkcja przeliczania notacji
    methods (Access = public)
        
        function results2 = notacja(~, przedrostek)
            switch przedrostek
                case 'giga [G]'
                    results2 = 1000000000;
                case 'mega [M]'
                    results2 = 1000000;
                case 'kilo [k]'
                    results2 = 1000;
                case 'mili [m]'
                    results2 = 0.001;
                case 'mikro [µ]'
                    results2 = 0.000001;
                case 'nano [n]'
                    results2 = 0.000000001;
                otherwise
                    results2 = 1;
            end
        end
    end
%     funkcja przeliczania jednostek
    methods (Access = public)
        function results = przelicz(app)
            m=1;
            if (app.j1 == "Dżul [J]")
                switch app.j2
                    case 'Dżul [J]'
                    case 'Brytyjska jednostka ciepła [BTU]'
                        m = 1/1055.87;
                    case 'Kaloria [cal]'
                        m = 1/4.19002;
                    case 'Elektronovolt [eV]'
                        m = 1/1.60218E-19;
                    case 'Ergon [erg]'
                        m = 1/0.0000001;
                    case 'Watogodzina [Wh]'
                        m = 1/3600;
                    otherwise
                        app.StatusTextArea.Value = 'blad';
                end

            elseif (app.j1 == "Brytyjska jednostka ciepła [BTU]")
                m = 1055.87;
                switch app.j2
                    case 'Dżul [J]'
                    case 'Brytyjska jednostka ciepła [BTU]'
                        m=1;
                    case 'Kaloria [cal]'
                        m=m/4.19002;
                    case 'Elektronovolt [eV]'
                        m=m/1.60218E-19;
                    case 'Ergon [erg]'
                        m=m/0.0000001;
                    case 'Watogodzina [Wh]'
                        m=m/3600;
                    otherwise
                        app.StatusTextArea.Value = 'blad';
                end

            elseif (app.j1 == "Kaloria [cal]")
                m = 4.184;
                switch app.j2
                    case 'Dżul [J]'
                    case 'Brytyjska jednostka ciepła [BTU]'
                        m=m/1055.87;
                    case 'Kaloria [cal]'
                        m=1;
                    case 'Elektronovolt [eV]'
                        m=m/1.60218E-19;
                    case 'Ergon [erg]'
                        m=m/0.0000001;
                    case 'Watogodzina [Wh]'
                        m=m/3600;
                    otherwise
                        app.StatusTextArea.Value = 'blad';
                end
            
            elseif (app.j1 == "Elektronovolt [eV]")
                m=1.60218E-19;
                switch app.j2
                    case 'Dżul [J]'
                    case 'Brytyjska jednostka ciepła [BTU]'
                        m=m/1055.87;
                    case 'Kaloria [cal]'
                        m=m/4.19002;
                    case 'Elektronovolt [eV]'
                        m=1;
                    case 'Ergon [erg]'
                        m=m/0.0000001;
                    case 'Watogodzina [Wh]'
                        m=m/3600;
                    otherwise
                        app.StatusTextArea.Value = 'blad';
                end
            
            elseif (app.j1 == "Ergon [erg]")
                m=0.0000001;
                switch app.j2
                    case 'Dżul [J]'
                    case 'Brytyjska jednostka ciepła [BTU]'
                        m=m/1055.87;
                    case 'Kaloria [cal]'
                        m=m/4.19002;
                    case 'Elektronovolt [eV]'
                        m=m/1.60218E-19;
                    case 'Ergon [erg]'
                        m=1;
                    case 'Watogodzina [Wh]'
                        m=m/3600;
                    otherwise
                        app.StatusTextArea.Value = 'blad';
                end

            elseif (app.j1 == "Watogodzina [Wh]")
                m=3600;
                switch app.j2
                    case 'Dżul [J]'
                    case 'Brytyjska jednostka ciepła [BTU]'
                        m=m/1055.87;
                    case 'Kaloria [cal]'
                        m=m/4.19002;
                    case 'Elektronovolt [eV]'
                        m=m/1.60218E-19;
                    case 'Ergon [erg]'
                        m=m/0.0000001;
                    case 'Watogodzina [Wh]'
                        m=1;
                    otherwise
                        app.StatusTextArea.Value = 'blad';
                end
            else
                app.StatusTextArea.Value = 'blad wyboru jednostki';
            end

% zamiana przedrostków na wartości liczbowe
            n1 = app.notacja(app.MnonikDropDown_2.Value);
            n2 = app.notacja(app.MnonikDropDown_3.Value);
% wyświetlenie statusu
            if n1 == n2 && m==1
                app.status = "Parametry są identyczne";
            else
                if n1==1 && n2~=1
                    app.status = sprintf("Przeliczono z %s, na %s %s", app.j1, app.MnonikDropDown_3.Value, app.j2);
                elseif n1~=1 && n2==1
                    app.status = sprintf("Przeliczono z %s %s, na %s", app.MnonikDropDown_2.Value, app.j1, app.j2);
                elseif n1==1 && n2==1
                    app.status = sprintf("Przeliczono z %s, na %s", app.j1, app.j2);
                else
                    app.status = sprintf("Przeliczono z %s %s, na %s %s",app.MnonikDropDown_2.Value, app.j1, app.MnonikDropDown_3.Value, app.j2);
                end
            end

            app.StatusTextArea.Value = app.status;

            app.WartowyjciowaEditField.Value = app.wartosc*m*n1/n2;
            results = app.wartosc*m*n1/n2;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: JednostkaDropDown
        function JednostkaDropDownValueChanged(app, event)
            app.j1 = app.JednostkaDropDown.Value;
            przelicz(app)
        end

        % Value changed function: JednostkaDropDown_2
        function JednostkaDropDown_2ValueChanged(app, event)
            app.j2 = app.JednostkaDropDown_2.Value;
            przelicz(app)
        end

        % Value changed function: WartowejciowaEditField
        function WartowejciowaEditFieldValueChanged(app, event)
            app.wartosc = app.WartowejciowaEditField.Value;
            przelicz(app)
        end

        % Value changed function: MnonikDropDown_2
        function MnonikDropDown_2ValueChanged(app, event)
            przelicz(app)
        end

        % Value changed function: MnonikDropDown_3
        function MnonikDropDown_3ValueChanged(app, event)
            przelicz(app)
        end

        % Button pushed function: ZapiszplikwynikowyButton
        function ZapiszplikwynikowyButtonPushed(app, event)
            [file,path,indx] = uiputfile('wyniki.txt','wyniki.txt');
            app.sciezkawynik=fullfile(path,file);
            app.plikwynik=file;
            
             % udalo sie zapisac plik wynikowy
            if indx ~=0
                plik=fopen(file,'w');
                fclose(plik);
                app.StatusTextArea.Value = 'Udało się zapisać plik wynikowy';
            else
                app.StatusTextArea.Value = 'Nie udało się zapisać, spróbuj powownie';
                app.plikwynik='';
            end
        end

        % Button pushed function: InstrukcjaprzeliczaniaseryjnegoButton
        function InstrukcjaprzeliczaniaseryjnegoButtonPushed(app, event)
            message = {'1. Ustaw przedrostki i jednostki zgodnie z preferencjami','2. Zapisz plik wynikowy', '3. Zaimportuj dane','(każdy pomiar w nowej linice)', '4. Oblicz seryjnie'};
            uialert(app.UIFigure,message,'Instrukcja przeliczania seryjnego','Icon','info');
        end

        % Button pushed function: ImportujdanedoobliczeButton
        function ImportujdanedoobliczeButtonPushed(app, event)
        [fileD,~,indx] = uigetfile('.txt');
         if indx ~=0
            app.dane= importdata(fileD);
            app.dlugoscdanych = length(app.dane);
            TF = isempty(app.dane);
            if TF == 1
                app.StatusTextArea.Value = 'Brak danych w pliku!';
                app.dlugoscdanych = 0;
            else
                app.StatusTextArea.Value = 'Udało się zaimportować dane';
            end
         else
            app.StatusTextArea.Value = 'Nie udało się pobrać danych, spróbuj powownie';
            app.dlugoscdanych = 0;
         end
        end

        % Button pushed function: ObliczButton
        function ObliczButtonPushed(app, event)
            %obliczenie seryjne
            if app.plikwynik==""
                app.StatusTextArea.Value = 'Brak wskazanego pliku do zapisu wyników!';
            elseif app.dlugoscdanych==0
                app.StatusTextArea.Value = 'Brak danych!';
            else
                plik = app.plikwynik;
                fid=fopen(plik,"a+");
                app.StatusTextArea.Value = 'Rozpoczęto obliczanie';
                fprintf(fid,'%s\n', app.status);
                for x = 1:app.dlugoscdanych
                    app.wartosc = app.dane(x);
                    wynik = app.przelicz();
                    fprintf(fid,'%f = %f\n',app.dane(x), wynik);
                end
                app.wartosc = 0;
                app.WartowejciowaEditField.Value=0;
                app.przelicz();
                fclose("all");
                app.StatusTextArea.Value = 'Zakończono generowanie pliku';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MenuprzeliczaniaseryjnegoLabel = uilabel(app.UIFigure);
            app.MenuprzeliczaniaseryjnegoLabel.Text = {'Menu przeliczania seryjnego'; ''};
            app.KonwerterjednostekenergiiLabel = uilabel(app.UIFigure);
            app.KonwerterjednostekenergiiLabel.Text = 'Konwerter jednostek energii';
            app.WartowejciowaEditFieldLabel = uilabel(app.UIFigure);
            app.WartowejciowaEditFieldLabel.Text = {'Wartość '; 'wejściowa'};
            app.WartowejciowaEditField = uieditfield(app.UIFigure, 'numeric');
            app.WartowejciowaEditField.ValueChangedFcn = createCallbackFcn(app, @WartowejciowaEditFieldValueChanged, true);
            app.JednostkaDropDown = uidropdown(app.UIFigure);
            app.JednostkaDropDown.Items = {'Dżul [J]', 'Brytyjska jednostka ciepła [BTU]', 'Kaloria [cal]', 'Elektronovolt [eV]', 'Ergon [erg]', 'Watogodzina [Wh]'};
            app.JednostkaDropDown.ValueChangedFcn = createCallbackFcn(app, @JednostkaDropDownValueChanged, true);
            app.JednostkaDropDown.Value = 'Dżul [J]';
            app.WartowyjciowaLabel = uilabel(app.UIFigure);
            app.WartowyjciowaLabel.Text = {'Wartość '; 'wyjściowa'};
            app.WartowyjciowaEditField = uieditfield(app.UIFigure, 'numeric');
            app.WartowyjciowaEditField.Editable = 'off';
            app.MnonikDropDown_2 = uidropdown(app.UIFigure);
            app.MnonikDropDown_2.Items = {'giga [G]', 'mega [M]', 'kilo [k]', '--', 'mili [m]', 'mikro [µ]', 'nano [n]'};
            app.MnonikDropDown_2.ValueChangedFcn = createCallbackFcn(app, @MnonikDropDown_2ValueChanged, true);
            app.MnonikDropDown_2.Value = '--';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.png';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status: ';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextArea.Editable = 'off';
            app.JednostkaDropDown_2 = uidropdown(app.UIFigure);
            app.JednostkaDropDown_2.Items = {'Dżul [J]', 'Brytyjska jednostka ciepła [BTU]', 'Kaloria [cal]', 'Elektronovolt [eV]', 'Ergon [erg]', 'Watogodzina [Wh]'};
            app.JednostkaDropDown_2.ValueChangedFcn = createCallbackFcn(app, @JednostkaDropDown_2ValueChanged, true);
            app.JednostkaDropDown_2.Value = 'Dżul [J]';
            app.MnonikDropDown_3 = uidropdown(app.UIFigure);
            app.MnonikDropDown_3.Items = {'giga [G]', 'mega [M]', 'kilo [k]', '--', 'mili [m]', 'mikro [µ]', 'nano [n]'};
            app.MnonikDropDown_3.ValueChangedFcn = createCallbackFcn(app, @MnonikDropDown_3ValueChanged, true);
            app.MnonikDropDown_3.Value = '--';
            app.JednostkaLabel = uilabel(app.UIFigure);
            app.JednostkaLabel.Text = 'Jednostka';
            app.PrzedrostekLabel = uilabel(app.UIFigure);
            app.PrzedrostekLabel.Text = 'Przedrostek';
            app.ZapiszplikwynikowyButton = uibutton(app.UIFigure, 'push');
            app.ZapiszplikwynikowyButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszplikwynikowyButtonPushed, true);
            app.ZapiszplikwynikowyButton.Icon = 'flder.png';
            app.ZapiszplikwynikowyButton.Text = 'Zapisz plik wynikowy';
            app.ImportujdanedoobliczeButton = uibutton(app.UIFigure, 'push');
            app.ImportujdanedoobliczeButton.ButtonPushedFcn = createCallbackFcn(app, @ImportujdanedoobliczeButtonPushed, true);
            app.ImportujdanedoobliczeButton.Icon = 'import.png';
            app.ImportujdanedoobliczeButton.Text = 'Importuj dane do obliczeń';
            app.ObliczButton = uibutton(app.UIFigure, 'push');
            app.ObliczButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczButtonPushed, true);
            app.ObliczButton.Icon = 'work.png';
            app.ObliczButton.Text = 'Oblicz';
            app.InstrukcjaprzeliczaniaseryjnegoButton = uibutton(app.UIFigure, 'push');
            app.InstrukcjaprzeliczaniaseryjnegoButton.ButtonPushedFcn = createCallbackFcn(app, @InstrukcjaprzeliczaniaseryjnegoButtonPushed, true);
            app.InstrukcjaprzeliczaniaseryjnegoButton.Icon = 'docs.png';
            app.InstrukcjaprzeliczaniaseryjnegoButton.Text = 'Instrukcja przeliczania seryjnego';
            app.Programzaliczeniowy2022rWiktorWrona259900Label = uilabel(app.UIFigure);
            app.Programzaliczeniowy2022rWiktorWrona259900Label.Text = 'Program zaliczeniowy 2022r Wiktor Wrona 259900 ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Konwerter_mnoznik_final2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end